classdef hlavniOkno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        testovacfrekvenceEditFieldLabel_7  matlab.ui.control.Label
        testovacfrekvenceEditFieldLabel_6  matlab.ui.control.Label
        testovacfrekvenceEditFieldLabel_5  matlab.ui.control.Label
        testovacfrekvenceEditFieldLabel_4  matlab.ui.control.Label
        testovacfrekvenceEditFieldLabel_3  matlab.ui.control.Label
        testovacfrekvenceEditFieldLabel_2  matlab.ui.control.Label
        zvyovnintenzityEditField       matlab.ui.control.EditField
        zvyovnintenzityEditFieldLabel  matlab.ui.control.Label
        maskovnnetestovanhouchaEditField  matlab.ui.control.EditField
        MakovnnetestovanhouchaLabel    matlab.ui.control.Label
        AUTOSCALEButton                matlab.ui.control.Button
        dokonceno                      matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        ULOZITButton                   matlab.ui.control.Button
        SPLumuEditField                matlab.ui.control.EditField
        SPLumuEditFieldLabel           matlab.ui.control.Label
        psmoumudoEditField             matlab.ui.control.EditField
        psmoumudoEditFieldLabel        matlab.ui.control.Label
        psmoumuodEditField             matlab.ui.control.EditField
        psmoumuodLabel                 matlab.ui.control.Label
        frekvencemaskovacchtnuEditField  matlab.ui.control.EditField
        frekvencemaskovacchtnuEditFieldLabel  matlab.ui.control.Label
        SPLmaskovacchtnuEditField      matlab.ui.control.EditField
        SPLmaskovacchtnuLabel          matlab.ui.control.Label
        testovacfrekvenceEditField     matlab.ui.control.EditField
        testovacfrekvenceEditFieldLabel  matlab.ui.control.Label
        SlysenoButton                  matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        NVODButton                     matlab.ui.control.Button
        nastavenhodnotyLabel           matlab.ui.control.Label
        NASTAVENButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    %% věci co bych mohl dodělat kdyby zbyl čas

    % nastavení kroků zvyšování intenzity např z 10 na 5 dB

    % lepší ukládání výsledku ne jen screenshot

    % vymyslet jaký používat typ filtru případně doplnit teorii o
    % filtrech do práce

    %filtrovat digitálně ně pře butter


    properties (Access = private)

        nastaveni
        navod


        %% nastavení "Globálních" proměnných
        freqVect = [125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000]; % defaultní nastavení testovacích frekvencí
        startMeasFlag = 0; % flag pro signalizaci začátku měření
        dontSaveTime = 0; % flag jestli se má ukláddt čas čekání na registraci testovacího tónu
        timeLeft = []; % vektor na ukládání času čekání na reakci levého ucha
        timeRight = []; % vektor na ukládání času čekání na reakci pravého ucha
        measCounter = 1; % počítadlo proběhlých měření
        leftMeas = 1; % flag pro úrčení měření pravého/levého ucha
        path = "C:\Users\pache\Desktop\bakalarka\screenyZapky\"; % defaultní adresář ukládání screenů

        %% proměnné nastavitelné v nastavení
        frekvencemaskovacihotonu = [];
        intenzitatestovacihotonu = [];
        sumfrekvenceod = [];
        sumfrekvencedo = [];
        sumintenzita = [];


        dokoncenoProcenta = 0; % procenta progresu měření
        ScaleFlag = 0; % Flag ktrý oddaluje a přibližuje výsledný graf

        steps = [0,10, 20, 30, 40, 50, 60, 70, 80, 90]; % kroky zvyšování intenzity schodovitého zvyšování intenzity v dB
        nabehyL = [];
        nabehyR = [];

        stepsSignal_R = [];
        stepsSignal_L = [];

        zvysovaniType = 0;
        maskType = 0;

        frekvenceUloz = [];
        levaUloz = [];
        pravaUloz = [];
    end

    methods (Access = public)
        %hlaní funkce pro přeposílání dat mezi main a nastavením
        %získává data z okna nastavení a některé data ve formátu string
        %konvertuje na vektor

        function importNastaveni(app,vektorFrekvenci,MaskToneFreqV,MaskToneIntV,MaskLowFreqV,MaskHighFreqV,IntSumV,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu)

            app.maskType=MaskovaniNetestovanehoUcha;
            if MaskovaniNetestovanehoUcha == 1
                app.maskovnnetestovanhouchaEditField.Value = 'ANO';
            else
                app.maskovnnetestovanhouchaEditField.Value= 'NE';
            end
            app.zvysovaniType=ZvysovaniIntenzityTonu;
            if ZvysovaniIntenzityTonu == 0
                app.zvyovnintenzityEditField.Value = 'SPOJITÝ';
            else
                app.zvyovnintenzityEditField.Value= 'SCHODOVITÝ';
            end

            app.testovacfrekvenceEditField.Value= vektorFrekvenci;
            app.freqVect = str2num(vektorFrekvenci);
            app.frekvencemaskovacchtnuEditField.Value = MaskToneFreqV;
            app.frekvencemaskovacihotonu = str2num(MaskToneFreqV);
            app.SPLmaskovacchtnuEditField.Value = MaskToneIntV;
            app.intenzitatestovacihotonu = str2num(MaskToneIntV);
            app.psmoumuodEditField.Value = MaskLowFreqV;
            app.sumfrekvenceod = str2num(MaskLowFreqV);
            app.psmoumudoEditField.Value = MaskHighFreqV;
            app.sumfrekvencedo = str2num(MaskHighFreqV);
            app.SPLumuEditField.Value = IntSumV;
            app.sumintenzita = str2num(IntSumV);


            %% spojité náběhy
            if app.zvysovaniType == 0
                Fs = 44100;
                LogBase = 20;
                RefU = 0.185;

                p = importdata('p.mat'); % imortování parametru do polyval k přepočtu dig. úrovně na napětí ze zvukovky a obráceně
                response_f = importdata('new_freq.mat'); % frekvenční osa frekvenční odezvy
                response_R = importdata('new_right.mat'); % pravý kanál frekvenční odezvy
                response_L = importdata('new_left.mat'); % levý kanál frekvenční odezvy

                t_max = 60;


                %kroky= min(app.steps):1/(Fs*(t_max/max(app.steps))):max(app.steps);
                kroky= 0:1/(Fs*(60/90)):90;
                kroky(end)= [];

                %t_spoj = linspace(0,t_max,numel(app.steps));

                f = app.freqVect;

                for k = 1:length(f)
                    frequence = f(k);
                    indexOfFreq = find(response_f == frequence);
                    for i = 1:length(kroky)
                        stepIntenzityR(i) = 10^((kroky(i) - response_R(indexOfFreq)) / LogBase) * RefU;
                        stepIntenzityDigitR(i) = ( stepIntenzityR(i) - p(2)) / p(1);
                        stepIntenzityL(i) = 10^((kroky(i) - response_L(indexOfFreq)) / LogBase) * RefU;
                        stepIntenzityDigitL(i) = ( stepIntenzityL(i) - p(2)) / p(1);
                    end           
                    app.nabehyL(k, :) = stepIntenzityDigitL;
                    app.nabehyR(k, :) = stepIntenzityDigitR;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.testovacfrekvenceEditField.Value = num2str(app.freqVect);
            %% spojité náběhy
            Fs = 44100;
            LogBase = 20;
            RefU = 0.185;

            p = importdata('p.mat'); % imortování parametru do polyval k přepočtu dig. úrovně na napětí ze zvukovky a obráceně
            response_f = importdata('new_freq.mat'); % frekvenční osa frekvenční odezvy
            response_R = importdata('new_right.mat'); % pravý kanál frekvenční odezvy
            response_L = importdata('new_left.mat'); % levý kanál frekvenční odezvy

            t_max = 60;

            %kroky= min(app.steps):1/(Fs*(t_max/max(app.steps))):max(app.steps);
            kroky= 0:1/(Fs*(60/90)):90;
            kroky(end)= [];

            %t_spoj = linspace(0,t_max,numel(app.steps));
            f = app.freqVect;

            for k = 1:length(f)
                frequence = f(k);
                indexOfFreq = find(response_f == frequence);
                  for i = 1:length(kroky)
                        stepIntenzityR(i) = 10^((kroky(i) - response_R(indexOfFreq)) / LogBase) * RefU;
                        stepIntenzityDigitR(i) = ( stepIntenzityR(i) - p(2)) / p(1);
                        stepIntenzityL(i) = 10^((kroky(i) - response_L(indexOfFreq)) / LogBase) * RefU;
                        stepIntenzityDigitL(i) = ( stepIntenzityL(i) - p(2)) / p(1);
                    end           
                    app.nabehyL(k, :) = stepIntenzityDigitL;
                    app.nabehyR(k, :) = stepIntenzityDigitR;
            end


        end

        % Button pushed function: NASTAVENButton
        function NASTAVENButtonPushed(app, event)
            %zasílání dat do okna nastavení
            app.nastaveni=nastaveni(app,app.freqVect,app.frekvencemaskovacihotonu,app.intenzitatestovacihotonu,app.sumfrekvenceod,app.sumfrekvencedo,app.sumintenzita,app.maskType,app.zvysovaniType);


        end

        % Button pushed function: NVODButton
        function NVODButtonPushed(app, event)
            %otevření okna návod
            app.navod=navod(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % delete(app.nastaveni)
            %delete(app.navod)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %% tlačítko pro start a restart měření

            %% stav kdy probíhá měření (možnost měření restartovat)
            if app.startMeasFlag == 0
                app.STARTButton.Text = 'RESTART';
                app.STARTButton.BackgroundColor = 'r';
                app.SlysenoButton.BackgroundColor = [0.47,0.67,0.19];
                app.startMeasFlag = 1;
                app.dontSaveTime = 1;
                SlysenoButtonPushed(app);
                app.dokonceno.Visible = "on";
                app.dokoncenoProcenta = 0;

                %% stav kdy měření neprobíhá (po doměření či restartu) - možnost znovu obnovit měření
            elseif app.startMeasFlag == 1
                %app.buttonPushed =1;
                app.STARTButton.Text = 'START';
                app.dokonceno.Visible = "off";
                app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                app.SlysenoButton.BackgroundColor = 'r';
                app.startMeasFlag = 0;
                app.measCounter = 1;
                app.leftMeas = 1;
                clear sound;


            end


        end

        % Button pushed function: SlysenoButton
        function SlysenoButtonPushed(app, event)
            %% deklarace parametrů pro měření
            Fs = 44100 ;   % nastavení vzorkovacího kmitočtu
            t_max = 60;   %časová délka signálu ale aby v gefu byla tahle hodnota je zapotřebí poděli pi*2(v grafu na x ukáže sice 10 ale)
            t = 0: (2*pi)/Fs : (2*pi*t_max - (2*pi)/Fs); %nastavení časové základny
            f = app.freqVect;%[125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];
            LogKoef = 20;  %koeficient kterým se násobí logaritmus-->  LogKoef*log(x)

            %P_sampled = importdata('funkce60.mat'); %importování exponenciální funkce
            %P_sampled = P_sampled'; % transpozice vektoru
            % P_sampled(end) = [];    % zkrácení vektoru aby měl požadovanou délku
            %Norm_P_sampled = P_sampled./ max(P_sampled);
            p = importdata('p.mat'); % imortování parametru do polyval k přepočtu dig. úrovně na napětí ze zvukovky a obráceně
            response_f = importdata('new_freq.mat'); % frekvenční osa frekvenční odezvy
            response_R = importdata('new_right.mat'); % pravý kanál frekvenční odezvy
            response_L = importdata('new_left.mat'); % levý kanál frekvenční odezvy
            RefU = 0.185; % referenční napětí (přibližně 1mW)


            % na začátku měření névé frekvence se přetane přehrávat
            % předchozí ton
            clear sound;

            %% ukládání času reakce na tetovací ton a procenta měření
            if app.dontSaveTime == 0
                %% procenta (nevim jak moc dobře funguje)
                Pridejprocenta = round(10000/(2*numel(app.freqVect)))/100; % počet procent pro jedno kolo měření
                app.dokoncenoProcenta = app.dokoncenoProcenta + Pridejprocenta; %přičítání procent na nové kolo
                if app.dokoncenoProcenta > 100 %pojistka aby procenta nepřesáhly 100%
                    app.dokoncenoProcenta = 100;
                end
                app.dokonceno.Text = append(string(app.dokoncenoProcenta)," % dokončeno"); % výpis procent

                %% ukládání času reakce na testovací ton
                if app.leftMeas == 0
                    app.timeRight(app.measCounter) = toc; % ukládání času reakce na tetovací ton pro pravé ucho
                elseif app.leftMeas == 1
                    if app.measCounter > 1
                        app.timeLeft(app.measCounter-1) = toc; % ukládání času reakce na tetovací ton pro levé ucho
                    end
                end
            else
                app.dontSaveTime =0;
            end


            if app.startMeasFlag == 1
                %% generování maskovacích tónů
                % ozkoušet..............................................................
                if ~isempty(app.frekvencemaskovacihotonu)
                    if numel(app.frekvencemaskovacihotonu) == numel(app.intenzitatestovacihotonu)
                        for i =1:numel(app.frekvencemaskovacihotonu)
                            indexOfFreq  = find(response_f == app.frekvencemaskovacihotonu(i));
                            testTonKarta(i) = 10^((app.intenzitatestovacihotonu(i) - response_R(indexOfFreq)) / LogKoef) * RefU;
                            %digitalniUrovenTest(i) = polyval(p, testTonKarta(i));
                            digitalniUrovenTest(i) = ( testTonKarta(i) - p(2)) / p(1);
                            MaskTone =digitalniUrovenTest(i).*sin(app.frekvencemaskovacihotonu(i)*t);
                            sound(MaskTone,Fs);
                        end
                    end
                end


                %% generování maskovacího šumu (pořešit filtr)
                if ~isempty(app.sumfrekvenceod)
                    if (numel(app.sumfrekvenceod) == numel(app.sumfrekvencedo) ) && (numel(app.sumfrekvenceod)== numel(app.sumintenzita) )%nevim proč nefinguje tahle podmínka
                        for i =1:numel(app.sumfrekvenceod)
                            noise = randn( 1,Fs);%FS
                            f_c = 1000;
                            NOISE = fft(noise); NOISE = NOISE(1:numel(NOISE)/2);% odfiltrování druhý půlky noisu

                            NOISE(1:app.sumfrekvenceod-1) = 0; NOISE(app.sumfrekvencedo+1:end) = 0; % pro úrčité pásmo frekvence filtrace

                            NOISE = [NOISE flip(NOISE)]; % zrcadlení ve spektru

                            noise = real(ifft(NOISE)); %převod ze spektra na šum
                            noise = repmat(noise,1,60);
                            indexOfFreqOD  = find(response_f == app.sumfrekvenceod); indexOfFreqDO  = find(response_f == app.sumfrekvencedo);
                            averageSPL = sum(response_R(indexOfFreqOD:indexOfFreqDO))/numel(response_R(indexOfFreqOD:indexOfFreqDO));
                            Uzvukovka = RefU*10^((app.sumintenzita-averageSPL)/LogKoef);
                            AMPL = ( Uzvukovka - p(2)) / p(1);

                            U1 = AMPL*sin(2*pi*f_c*t); U1(end) = [];
                            std(U1); % Standardní odchylka

                            noise = noise.*(std(U1)/std(noise));
                            %std(noise);%
                            sound(noise,Fs);

                        end
                    end
                end

                %% generování šumu pro maskování netestovaného ucha
                NEtnoise = randn( 1,Fs);
                f_c = 1000;
                NetNOISE = fft(NEtnoise);
                NetNOISE = NetNOISE(1:numel(NetNOISE)/2);
                NetNOISE(1:20-1) = 0; NetNOISE(20000+1:end) = 0;
                NetNOISE = [NetNOISE flip(NetNOISE)]; % zrcadlení ve spektru

                NEtnoise = real(ifft(NetNOISE)); %převod ze spektra na šum
                NEtnoise = repmat(NEtnoise,1,60);
                NetindexOfFreqOD  = find(response_f == 20); NetindexOfFreqDO  = find(response_f == 20000);
                NetaverageSPL = sum(response_R(NetindexOfFreqOD:NetindexOfFreqDO))/numel(response_R(NetindexOfFreqOD:NetindexOfFreqDO));
                UzvukNet = RefU*10^((10-NetaverageSPL)/LogKoef);
                AMPLnet = ( UzvukNet - p(2)) / p(1);

                U2 = AMPLnet*sin(2*pi*f_c*t); U2(end) = [];

                VyfitrovanyMaskNetest = NEtnoise.*(std(U2)/std(NEtnoise));


                %% hlavní část programu: MĚŘENÍ
                if app.measCounter < numel(f)+1 % vyhodnocení jestli má ještě probíhat měření v závislosti na počtu testovacích tónů
                    %% měření levého ucha
                    if app.leftMeas == 1
                        frequence = f(app.measCounter); % výběr frekvence
                        signal = sin(frequence*t); % generování sinusového signálu pro měření

                        if app.maskType == 1 % pokud je zaplé maskování netestovaného ucha zapne se zde
                            sound([zeros(size(t)); VyfitrovanyMaskNetest],Fs);
                        end

                        if app.zvysovaniType == 0
                            signal = signal.*app.nabehyL(app.measCounter, :);%*Norm_P_sampled; %generování spojitě zvyšujícího se signálu
                        elseif app.zvysovaniType == 1
                            indexOfFreq = find(response_f == frequence);
                            stepTime = length(t) / length(app.steps); % časová delka jednoh kroku
                            for i = 1:length(app.steps)
                                %% Schodové zvyšování intenzity přepočet hodnot v SPL v dB na digitální úroveň signálů pro jednotlivé frekvence
                                %napětí ze zvukovky pro jednotlivé kroky zvyšování intenzity
                                stepIntenzity(i) = RefU*10^((app.steps(i) - response_L(indexOfFreq)) / LogKoef);
                                stepIntenzityDigit(i) = ( stepIntenzity(i) - p(2)) / p(1);
                                % stepIntenzityDigit(i) = polyval(p, stepIntenzity(i)); % digitální velikost kroku
                                index_range = (i-1)*stepTime + 1 : i*stepTime;
                                app.stepsSignal_L(index_range) = stepIntenzityDigit(i);
                            end
                            % app.frekvencemaskovacchtnuEditField.Value =num2str(stepIntenzityDigit);
                            signal = signal.*app.stepsSignal_L; % generace testovacího signálu se schodovitým zvyšováním intenzity
                        end
                        sound([signal;zeros(size(t))],Fs);
                        %sound(signal,Fs);
                        tic
                        app.leftMeas = 0;
                        %% měření pravého ucha
                    elseif app.leftMeas == 0
                        frequence = f(app.measCounter); % výběr frekvence
                        signal = sin(frequence*t); % genarace sinusového signálu

                        if app.maskType == 1 % maskování netestovaného ucha (pokud je zvoleno)
                            sound([VyfitrovanyMaskNetest;zeros(size(t))],Fs)
                        end

                        if app.zvysovaniType == 0
                            signal = signal.*app.nabehyR(app.measCounter, :);%signal = signal.*Norm_P_sampled; %generování spojitého zvyšování intenzity test. tónu

                        elseif app.zvysovaniType == 1
                            indexOfFreq = find(response_f == frequence);
                            stepTime = length(t) / length(app.steps); % časová delka jednoh kroku
                            for i = 1:length(app.steps)
                                %% Schodové zvyšování intenzity
                                %napětí ze zvukovky přepočtena z dB
                                stepIntenzity(i) = RefU*10^((app.steps(i) - response_R(indexOfFreq)) / LogKoef);
                                % digitální velikost kroku
                                %stepIntenzityDigit(i) = polyval(p, stepIntenzity(i));
                                stepIntenzityDigit(i) = ( stepIntenzity(i) - p(2)) / p(1);
                                index_range = (i-1)*stepTime + 1 : i*stepTime;
                                app.stepsSignal_R(index_range) = stepIntenzityDigit(i);
                            end
                            % app.testovacfrekvenceEditField.Value = num2str(stepIntenzityDigit);
                            signal = signal.*app.stepsSignal_R;
                        end
                        sound([zeros(size(t)); signal],Fs);
                        tic
                        app.measCounter =  app.measCounter + 1;
                        app.leftMeas =1;
                    end


                else
                    %restarting
                    app.startMeasFlag = 0;
                    app.STARTButton.Text = 'START';
                    app.dokonceno.Visible = "off";
                    app.dokoncenoProcenta = 0;
                    app.dokonceno.Text = append(string(app.dokoncenoProcenta)," % dokončeno");
                    app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                    app.SlysenoButton.BackgroundColor = 'r';

                    if app.zvysovaniType == 0

                        for k= 1:length(app.timeLeft)
                            timePointLeft(k) = floor((app.timeLeft(k)/t_max)*length(t));
                            nabeh =  app.nabehyL(k,:);
                            ampiltudeLeft(k)  = nabeh(timePointLeft(k));%Norm_P_sampled(timePointLeft(k));

                        end
                        for k = 1:length(app.timeRight)
                            timePointRight(k) = floor((app.timeRight(k)/t_max)*length(t));
                            nabeh =  app.nabehyR(k,:);
                            ampiltudeRight(k) =nabeh(timePointLeft(k));% Norm_P_sampled(timePointRight(j));
                        end


                        %% přepočet na dB u spojitého zvyšování intenzity
                        for i = 1:numel(ampiltudeRight)
                            indexFreq = find(response_f == f(i)); % vyhledání frekvence ve vektoru frekvencí frekvenční odezvy
                            rightChanel = response_R(indexFreq); % SPL úroveň frekvenční odezvy pravý kanál
                            leftChanel = response_L(indexFreq);  % SPL úroveň frekvenční odezvy levý kanál
                            vstupniNapetiRigt(i) = polyval(p, ampiltudeRight(i)); % přepočet digitálního napětí na napětí na výstupu zvukovky pravý kanál
                            vstupniNapetiLeft(i) = polyval(p, ampiltudeLeft(i));  % přepočet digitálního napětí na napětí na výstupu zvukovky levý kanál
                            SPLleft(i) = leftChanel + LogKoef*log10(vstupniNapetiLeft(i)/RefU);   % SPL na výstupu sluchátek levý kanál
                            SPLRight(i) = rightChanel + LogKoef*log10(vstupniNapetiRigt(i)/RefU); % SPL na výstupu sluchátek pravý kanál
                            %SPLRight(i) = abs(SPLRight(i));
                        end

                        %% přepočet na dB u schodovitého zvyšování intenzity
                    elseif app.zvysovaniType == 1

                        for i = 1:numel(app.timeLeft)
                            % %% metoda, že vezmu data SPL level z app.steps
                            % pravy= app.timeRight(i)/(t_max/numel(app.steps)); % hledání časového intervalu schodové funkce
                            % levy=  app.timeLeft(i)/(t_max/numel(app.steps));  % hledání časového intervalu schodové funkce
                            % indexRight = floor(pravy);                        % hledání časového intervalu schodové funkce
                            % indexLeft = floor(levy);                          % hledání časového intervalu schodové funkce
                            % SPLleft(i) = app.steps(indexLeft+1); % výběr jednotlivých rkokův dB pro levé ucho
                            % SPLRight(i) = app.steps(indexRight+1); % výběr jednotlivých rkokův dB pro pravé ucho

                            %% metoda přepočítat zpátky
                            pravy(i) = app.stepsSignal_R(round(app.timeRight(i)*Fs));
                            levy(i) = app.stepsSignal_L(round(app.timeLeft(i)*Fs));
                            vstupniNapetiRigt(i) = polyval(p, pravy(i)); % přepočet digitálního napětí na napětí na výstupu zvukovky pravý kanál
                            vstupniNapetiLeft(i) = polyval(p, levy(i));
                            SPLleft(i) = response_L(find(response_f == f(i))) + LogKoef*log10(vstupniNapetiRigt(i)/RefU);   % SPL na výstupu sluchátek levý kanál
                            SPLRight(i) = response_R(find(response_f == f(i))) + LogKoef*log10(vstupniNapetiLeft(i)/RefU); % SPL na výstupu sluchátek pravý kanál
                        end

                    end
                    %% uložení dat
                    app.frekvenceUloz = f;
                    app.levaUloz = SPLleft;
                    app.pravaUloz= SPLRight;

                    ax0 = app.UIAxes;
                    %% vykreslení audiogramu pokud není použito maskování netestovaného ucha
                    if app.maskType == 0
                        plot(ax0,log2(f),SPLRight,'r-o',log2(f),SPLleft,'b-x','MarkerSize',7,'LineWidth',1.5);
                        legend(ax0, 'Pravé ucho', 'Levé ucho', 'Location','southeast');
                        %% vykreslení audiogramu pokud je použito maskování netestovaného ucha
                    elseif app.maskType == 1
                        plot(ax0,log2(f),SPLRight,'rs-',log2(f),SPLleft,'b-^','MarkerSize',7,'LineWidth',1.5);
                        legend(ax0, 'Pravé ucho', 'Levé ucho', 'Location','southeast');
                    end

                    zobrazeneFrekvence = arrayfun(@num2str, f, 'UniformOutput', false);
                    app.UIAxes.XTickLabel = zobrazeneFrekvence;
                    app.UIAxes.XTick = log2(f);
                    app.UIAxes.XLim = [log2(min(app.freqVect)) log2(max(app.freqVect))];


                    if  ~isempty(app.frekvencemaskovacihotonu)
                        hold( ax0, 'on' )
                        h = stem(ax0,log2(app.frekvencemaskovacihotonu), app.intenzitatestovacihotonu,'Marker', "_");
                        h.Color = 'm';
                        h.LineWidth = 2;
                        legend(ax0, 'Pravé ucho', 'Levé ucho','Maskovací tón', 'Location','southeast');

                    end
                    if  ~isempty(app.sumintenzita)
                        hold( ax0, 'on' )
                        pasmo(1:numel(log2(app.sumfrekvenceod:app.sumfrekvencedo))) = app.sumintenzita;
                        h = stem(ax0,log2(app.sumfrekvenceod:app.sumfrekvencedo), pasmo , 'Marker', ".");
                        h.Color = 'k';
                        h.LineWidth = 2;

                        if  ~isempty(app.frekvencemaskovacihotonu)
                            legend(ax0, 'Pravé ucho', 'Levé ucho','Maskovací tón','Maskovací šum', 'Location','southeast');
                        else
                            legend(ax0, 'Pravé ucho', 'Levé ucho','Maskovací šum', 'Location','southeast');
                        end

                    end
                    hold( ax0, 'off' )
                    app.measCounter = 1;
                    clear sound;

                end

            end
        end

        % Value changed function: testovacfrekvenceEditField
        function testovacfrekvenceEditFieldValueChanged(app, event)
            value = app.testovacfrekvenceEditField.Value;

        end

        % Value changed function: frekvencemaskovacchtnuEditField
        function frekvencemaskovacchtnuEditFieldValueChanged(app, event)
            value = app.frekvencemaskovacchtnuEditField.Value;

        end

        % Value changed function: SPLmaskovacchtnuEditField
        function SPLmaskovacchtnuEditFieldValueChanged(app, event)
            value = app.SPLmaskovacchtnuEditField.Value;

        end

        % Value changed function: psmoumuodEditField
        function psmoumuodEditFieldValueChanged(app, event)
            value = app.psmoumuodEditField.Value;

        end

        % Value changed function: psmoumudoEditField
        function psmoumudoEditFieldValueChanged(app, event)
            value = app.psmoumudoEditField.Value;

        end

        % Value changed function: SPLumuEditField
        function SPLumuEditFieldValueChanged(app, event)
            value = app.SPLumuEditField.Value;

        end

        % Button pushed function: ULOZITButton
        function ULOZITButtonPushed(app, event)
            %% zatím jen ukládáme print screen
            screen = getframe(app.UIFigure);
            str = append(app.path,app.FilenameEditField.Value,".png");
            fileName = convertStringsToChars(str);
            % Uložení obrázku do souboru
            %% na tohle se ještě podívat
            if exist(fileName, 'file')
                answer = questdlg('Soubor s tímto názvem již existuje. Chcete ho přepsat?', ...
                    'Uložit obrázek', 'Ano', 'Ne', 'Ne');
                if strcmp(answer, 'Ne')
                    return;
                end

            end
            imwrite(screen.cdata, fileName);


            % Vytvoření struktury
            data_structure.frekvence = app.frekvenceUloz;
            data_structure.leva = app.levaUloz;
            data_structure.prava = app.pravaUloz;

            str2 = append(app.path,app.FilenameEditField.Value,".mat");
            fileName2 = convertStringsToChars(str2);
            % Uložení struktury do souboru
            save(fileName2, 'data_structure');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %% výběr adresáře pro ukládání screenu
            selectedFolder = uigetdir(app.path);
            app.path = string(selectedFolder);
            app.path =append(app.path,"\");
            % app.LabelPath.Text = char(app.path);
            %app.SPLumuEditField.Value = char(app.path);
        end

        % Button pushed function: AUTOSCALEButton
        function AUTOSCALEButtonPushed(app, event)
            if app.ScaleFlag == 0
                zobrazeneFrekvence = arrayfun(@num2str, app.freqVect, 'UniformOutput', false);
                app.UIAxes.XTickLabel = zobrazeneFrekvence;
                app.UIAxes.XTick = log2(app.freqVect);
                %app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.UIAxes.XLim = [log2(min(app.freqVect)) log2(max(app.freqVect))];
                %app.UIAxes.XTickMode = 'auto';
                %app.UIAxes.XTickLabelMode = 'auto';
                app.AUTOSCALEButton.Text = 'ZPĚT';
                app.ScaleFlag = 1;


            elseif app.ScaleFlag == 1
                app.UIAxes.XTickMode = 'manual';
                app.UIAxes.XTickLabelMode = 'manual';
                %app.UIAxes.XLim = [0 8000];
                app.UIAxes.XLim = [log2(min(app.freqVect)) log2(max(app.freqVect))];
                zobrazeneFrekvence = arrayfun(@num2str, app.freqVect, 'UniformOutput', false);
                app.UIAxes.XTickLabel = zobrazeneFrekvence;
                app.UIAxes.XTick = log2(app.freqVect);
                app.UIAxes.YLim = [-10 90];
                %app.UIAxes.XTick = [1 1000 2000 3000 4000 5000 6000 7000 8000];
                %app.UIAxes.XTickLabel = {'1'; '1000'; '2000'; '3000'; '4000'; '5000'; '6000'; '7000'; '8000'};

                app.AUTOSCALEButton.Text = 'AUTOSCALE';
                app.ScaleFlag = 0;
            end
        end

        % Value changed function: maskovnnetestovanhouchaEditField
        function maskovnnetestovanhouchaEditFieldValueChanged(app, event)
            value = app.maskovnnetestovanhouchaEditField.Value;

        end

        % Value changed function: zvyovnintenzityEditField
        function zvyovnintenzityEditFieldValueChanged(app, event)
            value = app.zvyovnintenzityEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AUDIOGRAM')
            xlabel(app.UIAxes, 'Frekvence Hz')
            ylabel(app.UIAxes, 'SPL dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [6.9658 12.9658];
            app.UIAxes.YLim = [-10 90];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.XTick = [6.9658 7.9658 8.9658 9.9658 10.5507 10.9658 11.5507 11.9658 12.5507 12.9658];
            app.UIAxes.XTickLabel = {'125'; '250'; '500'; '1000'; '1500'; '2000'; '3000'; '4000'; '6000'; '8000'};
            app.UIAxes.Tag = 'tagGrafu';
            app.NASTAVENButton = uibutton(app.UIFigure, 'push');
            app.NASTAVENButton.ButtonPushedFcn = createCallbackFcn(app, @NASTAVENButtonPushed, true);
            app.NASTAVENButton.Text = 'NASTAVENÍ';
            app.nastavenhodnotyLabel = uilabel(app.UIFigure);
            app.nastavenhodnotyLabel.Text = 'nastavené hodnoty:';
            app.NVODButton = uibutton(app.UIFigure, 'push');
            app.NVODButton.ButtonPushedFcn = createCallbackFcn(app, @NVODButtonPushed, true);
            app.NVODButton.Text = 'NÁVOD';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SlysenoButton = uibutton(app.UIFigure, 'push');
            app.SlysenoButton.ButtonPushedFcn = createCallbackFcn(app, @SlysenoButtonPushed, true);
            app.SlysenoButton.Text = 'SLYŠENO';
            app.testovacfrekvenceEditFieldLabel = uilabel(app.UIFigure);
            app.testovacfrekvenceEditFieldLabel.Text = 'testovací frekvence:';
            app.testovacfrekvenceEditField = uieditfield(app.UIFigure, 'text');
            app.testovacfrekvenceEditField.ValueChangedFcn = createCallbackFcn(app, @testovacfrekvenceEditFieldValueChanged, true);
            app.testovacfrekvenceEditField.Editable = 'off';
            app.SPLmaskovacchtnuLabel = uilabel(app.UIFigure);
            app.SPLmaskovacchtnuLabel.Text = 'SPL maskovacích tónu:';
            app.SPLmaskovacchtnuEditField = uieditfield(app.UIFigure, 'text');
            app.SPLmaskovacchtnuEditField.ValueChangedFcn = createCallbackFcn(app, @SPLmaskovacchtnuEditFieldValueChanged, true);
            app.SPLmaskovacchtnuEditField.Editable = 'off';
            app.frekvencemaskovacchtnuEditFieldLabel = uilabel(app.UIFigure);
            app.frekvencemaskovacchtnuEditFieldLabel.Text = 'frekvence maskovacích tónu:';
            app.frekvencemaskovacchtnuEditField = uieditfield(app.UIFigure, 'text');
            app.frekvencemaskovacchtnuEditField.ValueChangedFcn = createCallbackFcn(app, @frekvencemaskovacchtnuEditFieldValueChanged, true);
            app.frekvencemaskovacchtnuEditField.Editable = 'off';
            app.psmoumuodLabel = uilabel(app.UIFigure);
            app.psmoumuodLabel.Text = 'pásmo šumu od:';
            app.psmoumuodEditField = uieditfield(app.UIFigure, 'text');
            app.psmoumuodEditField.ValueChangedFcn = createCallbackFcn(app, @psmoumuodEditFieldValueChanged, true);
            app.psmoumuodEditField.Editable = 'off';
            app.psmoumudoEditFieldLabel = uilabel(app.UIFigure);
            app.psmoumudoEditFieldLabel.Text = 'pásmo šumu do:';
            app.psmoumudoEditField = uieditfield(app.UIFigure, 'text');
            app.psmoumudoEditField.ValueChangedFcn = createCallbackFcn(app, @psmoumudoEditFieldValueChanged, true);
            app.psmoumudoEditField.Editable = 'off';
            app.SPLumuEditFieldLabel = uilabel(app.UIFigure);
            app.SPLumuEditFieldLabel.Text = 'SPL šumu:';
            app.SPLumuEditField = uieditfield(app.UIFigure, 'text');
            app.SPLumuEditField.ValueChangedFcn = createCallbackFcn(app, @SPLumuEditFieldValueChanged, true);
            app.SPLumuEditField.Editable = 'off';
            app.ULOZITButton = uibutton(app.UIFigure, 'push');
            app.ULOZITButton.ButtonPushedFcn = createCallbackFcn(app, @ULOZITButtonPushed, true);
            app.ULOZITButton.Text = 'ULOŽIT';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'název souboru';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'foldr.png');
            app.Button.Text = '';
            app.dokonceno = uilabel(app.UIFigure);
            app.dokonceno.Visible = 'off';
            app.dokonceno.Text = '0 % dokončeno';
            app.AUTOSCALEButton = uibutton(app.UIFigure, 'push');
            app.AUTOSCALEButton.ButtonPushedFcn = createCallbackFcn(app, @AUTOSCALEButtonPushed, true);
            app.AUTOSCALEButton.Text = 'AUTOSCALE';
            app.MakovnnetestovanhouchaLabel = uilabel(app.UIFigure);
            app.MakovnnetestovanhouchaLabel.Text = 'maskování netestovaného ucha: ';
            app.maskovnnetestovanhouchaEditField = uieditfield(app.UIFigure, 'text');
            app.maskovnnetestovanhouchaEditField.ValueChangedFcn = createCallbackFcn(app, @maskovnnetestovanhouchaEditFieldValueChanged, true);
            app.maskovnnetestovanhouchaEditField.Value = 'NE';
            app.zvyovnintenzityEditFieldLabel = uilabel(app.UIFigure);
            app.zvyovnintenzityEditFieldLabel.Text = 'zvyšování intenzity:';
            app.zvyovnintenzityEditField = uieditfield(app.UIFigure, 'text');
            app.zvyovnintenzityEditField.ValueChangedFcn = createCallbackFcn(app, @zvyovnintenzityEditFieldValueChanged, true);
            app.zvyovnintenzityEditField.Value = 'SPOJITÝ';
            app.testovacfrekvenceEditFieldLabel_2 = uilabel(app.UIFigure);
            app.testovacfrekvenceEditFieldLabel_2.Text = 'Hz';
            app.testovacfrekvenceEditFieldLabel_3 = uilabel(app.UIFigure);
            app.testovacfrekvenceEditFieldLabel_3.Text = 'dB';
            app.testovacfrekvenceEditFieldLabel_4 = uilabel(app.UIFigure);
            app.testovacfrekvenceEditFieldLabel_4.Text = 'dB';
            app.testovacfrekvenceEditFieldLabel_5 = uilabel(app.UIFigure);
            app.testovacfrekvenceEditFieldLabel_5.Text = 'Hz';
            app.testovacfrekvenceEditFieldLabel_6 = uilabel(app.UIFigure);
            app.testovacfrekvenceEditFieldLabel_6.Text = 'Hz';
            app.testovacfrekvenceEditFieldLabel_7 = uilabel(app.UIFigure);
            app.testovacfrekvenceEditFieldLabel_7.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hlavniOkno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end